classdef ditton < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        zhaoRongkangMATLAB2021bLabel  matlab.ui.control.Label
        Button_3                      matlab.ui.control.Button
        Button_2                      matlab.ui.control.Button
        Button                        matlab.ui.control.Button
        a1_8                          matlab.ui.control.Button
        a1_7                          matlab.ui.control.Button
        a1_6                          matlab.ui.control.Button
        a1_5                          matlab.ui.control.Button
        a1_4                          matlab.ui.control.Button
        a1_3                          matlab.ui.control.Button
        a1_2                          matlab.ui.control.Button
        a1                            matlab.ui.control.Button
    end

    
    properties (Access = private)
     
        ii ;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ii=1;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
%             msgbox('I will be bake')
            pause(1)
%             run ditton.mlapp
        
        end

        % Button pushed function: a1
        function a1ButtonPushed(app, event)
     
if app.ii<2
    app.ii=2;
else 
    set_param(gcb,'Port',num2str(app.ii));
    app.ii=app.ii+1;
end

        end

        % Button pushed function: a1_2
        function a1_2ButtonPushed(app, event)
              
        pathe='IPB_5ms_NewValveActuation/IPB_5ms_NewValveActuation'
inpu=['IPB_ValveTargets','/6']
putp=['IPB_ValveController','/22']
add_line(pathe,inpu,putp,'autorouting','on')
        end

        % Button pushed function: a1_3
        function a1_3ButtonPushed(app, event)
           lai =Simulink.Mask.get('HalUintCtrl/HydCtrlEst/EV_AV_Unit');
% Simulink.Mask.create('csv/VCP_OneValve/VCP_StateMachine');
qu=Simulink.Mask.get('HalUintCtrl/HydCtrlEst');

for ii=1:numel(lai.Parameters)
laiparam=lai.getParameter(lai.Parameters(1, ii).Name);
 %if regexpi(lai.Parameters(1, ii).Name,'.*_Duration')%阀策略信号、压力目标信号名称统一
%                  Blocks{i,1}= regexprep( Blocks{i,1},'T_','');
%                
%                   Blocks{i,1}=[Blocks{i,1},'_Duration']
% end


a=qu.addParameter(laiparam);

end
        end

        % Button pushed function: a1_4
        function a1_4ButtonPushed(app, event)
            a=get(gcbh);
amxl=max(a.Ports)/2*35;

px=(a.Position(1)+a.Position(3))/2;
py=(a.Position(2)+a.Position(4))/2;
a.Position(1)=min(px-amxl*0.618/2,a.Position(1));
a.Position(3)=max(px+amxl*0.618/2,a.Position(3));

a.Position(2)=py-amxl;
a.Position(4)=py+amxl;
set_param(gcb,'Position',a.Position);
        end

        % Button pushed function: a1_5
        function a1_5ButtonPushed(app, event)
            clear
clc
lai =Simulink.Mask.get(gcb);


for ii=1:numel(lai.Parameters)
    laiparam=lai.Parameters(ii);
    
laiparam.set('Type','edit','Value',laiparam.Value);
end
        end

        % Button pushed function: a1_6
        function a1_6ButtonPushed(app, event)
           Path= gcs;
           app.UIFigure.WindowStyle = 'normal';
           ass=questdlg('Would you like a dessert?', ...
	'Dessert Menu', ...
	'Inport','Outport','ALL','Outport');
           app.UIFigure.WindowStyle = 'alwaysontop';
           switch ass
    case 'Inport'
        
        asss = 1;
    case 'Outport'
       
        asss = 2;
    case 'ALL'
              asss = 3;
           end
InportCell = find_system(Path,'SearchDepth','1','BlockType','Inport'); 
OutportCell = find_system(Path,'SearchDepth',1,'BlockType','Outport');  %获取顶层Inport模块路径


if mod(asss,2)
     for i = 1:length(InportCell)   
    InportName = get_param(InportCell{i},'Name');  
    InportName=[InportName '_sim'];% 模块名称 
     InportPosition= get_param(InportCell{i},'Position'); 
    aaas=[700 0 500 0 ];
    InportPosition=InportPosition-aaas;
Inport_handle=add_block('simulink/Sources/From Workspace',[Path,'/' ,InportName]);
set_param(Inport_handle,'Position',InportPosition,'VariableName',InportName);

     end
end
if asss~=1
 for i = 1:length(OutportCell)   
     OutportName = get_param(OutportCell{i},'Name');  
     OutportName=[OutportName '_sim'];% 模块名称 
    OutportPosition= get_param(OutportCell{i},'Position'); 
    aaas=[300 0 500 0 ];
    bbbs=[500 0 700 0 ];
    OutportPosition=OutportPosition+aaas;

    Outport_handle=add_block('simulink/Sources/From Workspace',[Path,'/' ,OutportName]);
    set_param(Outport_handle,'Position',OutportPosition,'VariableName',OutportName);
    Outport_handle=add_block('simulink/Ports & Subsystems/Out1',[Path,'/' ,OutportName,'_Sim']);
    set_param(Outport_handle,'position',OutportPosition+bbbs);
    %连线
    add_line(Path,[OutportName ,'/1'],[OutportName ,'_Sim','/1']);
    LineHandleStruct = get(Outport_handle,'LineHandles');
    LineHandle = LineHandleStruct.Inport;
    set(LineHandle,'Name',[OutportName '_t']) 
 end
end

        end

        % Button pushed function: a1_7
        function a1_7ButtonPushed(app, event)
            Simulink.BlockDiagram.arrangeSystem(gcb);

lineHandles=find_system(gcbh,'FindAll','On','SearchDepth',1,'Type','Line');
Simulink.BlockDiagram.routeLine(lineHandles);%整理连线布局
        end

        % Button pushed function: a1_8
        function a1_8ButtonPushed(app, event)
             alll=evalin('base', 'whos')


OutportPosition=[0 0 120  40];
bbbs=[180 0 180 0 ];
nestone=[0 90 0 90  ];
 Path= gcs;
 add_block('simulink/Ports & Subsystems/Subsystem',[Path,'/SIM' ]);
 Path=[Path,'/SIM' ];
for i=1:length(alll)
     xname=alll(i).name;
    if  regexpi(alll(i).name,'.*_sim')
        Inport_handle=add_block('simulink/Sources/From Workspace',[Path,'/' ,xname]);
set_param(Inport_handle,'Position',OutportPosition,'VariableName',xname)
Outport_handle=add_block('simulink/Ports & Subsystems/Out1',[Path,'/' ,xname,'_Sim']);
   set_param(Outport_handle,'position',OutportPosition+bbbs);
    %连线
 add_line(Path,[xname ,'/1'],[xname ,'_Sim','/1']);
  LineHandleStruct = get(Outport_handle,'LineHandles');
    LineHandle = LineHandleStruct.Inport;
    set(LineHandle,'Name',[xname]) ;
    Simulink.sdi.markSignalForStreaming(LineHandle,'on');
    OutportPosition=OutportPosition+nestone;
 
    end

end
 eval(['clear',' ' ,'alll']);%去除原有信号
        end

        % Button pushed function: Button
        function ButtonButtonPushed(app, event)
       % V1.0
% 2023-1-4
% zrkkk
% MATLAB 2015b
model_set=[30,14 ];%参数分别代表模型长和高，可自行修改
int_distance=100;%输入模块和模型的间距，可自行修改
out_distance=100;%输出模块和模型的间距，可自行修改

%% 调节模块大小
a=get(gcbh);
amxl=max(a.Ports)/2*35;

px=(a.Position(1)+a.Position(3))/2;
py=(a.Position(2)+a.Position(4))/2;
a.Position(1)=min(px-amxl*0.618/2,a.Position(1));
a.Position(3)=max(px+amxl*0.618/2,a.Position(3));

a.Position(2)=py-amxl;
a.Position(4)=py+amxl;
set_param(gcb,'Position',a.Position);
%% 搜索本层输入输出信号
all=get(gcbh);%获取目标

Path=all.Path;%目标模块路径
Blocks =all.Blocks;%输入输出模块名称
PortConnectivity=all.PortConnectivity;%输入输出模块数量及点位信息
InportCell = find_system(Path,'SearchDepth','1','BlockType','Inport'); 
OurportCell = find_system(Path,'SearchDepth','1','BlockType','Outport');  %获取本层Outport模块路径

%% 添加输入输出端


a='';
%名称重命名
% for i =1:numel(Blocks)  
% %             Blocks{i,1}= regexprep(Blocks{i,1},'B_','');
%          if regexpi(Blocks{i,1},'T_.*')%阀策略信号、压力目标信号名称统一
%                  Blocks{i,1}= regexprep( Blocks{i,1},'T_','');
%                
%                   Blocks{i,1}=[Blocks{i,1},'_Duration']
%          end 
%               if regexpi(Blocks{i,1},'.*(Duration).*')%阀策略信号、压力目标信号名称统一
%                  Blocks{i,1}= regexprep( Blocks{i,1},'_Duration_','_Duration');
%                  Blocks{i,1}=[Blocks{i,1}(1:2),Blocks{i,1}(end-1:end),Blocks{i,1}(3:end-2)]
%               end 
% end
for i =1:numel(PortConnectivity)  
if PortConnectivity(i).Position(1)==PortConnectivity(1).Position(1)
%  continue
     if PortConnectivity(i).SrcBlock >0
     
      elseif  ismember([Path,'/' ,Blocks{i,1}],InportCell)
          [intnum,intwhee]= ismember([Path,'/' ,Blocks{i,1}],InportCell);
         add_line(Path,[Blocks{i,1},'/1'],[all.Name,'/',PortConnectivity(i).Type],'autorouting','smart');
     elseif  ismember([Path,'/' ,Blocks{i,1}],OurportCell);
        
 else
Blocks{i,1}=[a,Blocks{i,1}];
Inport_handle=add_block('simulink/Ports & Subsystems/In1',[Path,'/' ,Blocks{i,1}]);
   
   
 positiontaype=[-model_set(1)-int_distance -model_set(2)/2 -int_distance model_set(2)/2] ; 
 Inport_position = positiontaype+[PortConnectivity(i).Position, PortConnectivity(i).Position];
   set_param(Inport_handle,'position',Inport_position);
    %连线   
add_line(Path,[Blocks{i,1},'/1'],[all.Name,'/',PortConnectivity(i).Type],'autorouting','smart');
    end
else
   
%      continue
    k=numel(PortConnectivity)-i;
       Blocks{end-k,1}=[a,Blocks{end-k,1}];
     if PortConnectivity(end-k).DstBlock >0
     elseif  ismember([Path,'/' ,Blocks{end-k,1}],OurportCell)
           
          [outtnum,outtwhee]= ismember([Path,'/' ,Blocks{i,1}],OurportCell);
        add_line(Path,[all.Name,'/',PortConnectivity(i).Type],[Blocks{end-k,1},'/1']);
         
     elseif  ismember([Path,'/' ,Blocks{end-k,1}],InportCell)
          
     else

   Outport_handle=add_block('simulink/Ports & Subsystems/Out1',[Path,'/' ,Blocks{end-k,1}]);
 
   
 positiontaype=[out_distance -model_set(2)/2 model_set(1)+out_distance model_set(2)/2] ; 
 Outport_position = positiontaype+[PortConnectivity(i).Position, PortConnectivity(i).Position];
   set_param(Outport_handle,'position',Outport_position);
    %连线
 add_line(Path,[all.Name,'/',PortConnectivity(i).Type],[Blocks{end-k,1},'/1']);
    end
    
end
end

        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            % V1.0
% 2021-1-4
% Shiwen Chen
% MATLAB 2015b

InportCell = find_system(bdroot,'SearchDepth','1','BlockType','Inport');  %获取顶层Inport模块路径
for i = 1:length(InportCell)  
    InportHandle = get_param(InportCell{i},'Handle');  %信号线句柄
    LineHandleStruct = get(InportHandle,'LineHandles');
    LineHandle = LineHandleStruct.Outport;
    set(LineHandle,'Name','')  %清除信号线名称
end

OurportCell = find_system(bdroot,'SearchDepth','1','BlockType','Outport');  %获取顶层Outport模块路径
for i = 1:length(OurportCell)  
    OutportHandle = get_param(OurportCell{i},'Handle');  %信号线句柄
    LineHandleStruct = get(OutportHandle,'LineHandles');
    LineHandle = LineHandleStruct.Inport;
    set(LineHandle,'Name','')  %清除信号线名称
end
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            % V1.0
% 2021-1-4
% Shiwen Chen
% MATLAB 2015b

InportCell = find_system(bdroot,'SearchDepth',1,'BlockType','Inport');  %获取顶层Inport模块路径
for i = 1:length(InportCell)  
    InportName = get_param(InportCell{i},'Name');  %输入模块名称
    InportHandle = get_param(InportCell{i},'Handle');  %信号线句柄
    LineHandleStruct = get(InportHandle,'LineHandles');
    LineHandle = LineHandleStruct.Outport;
    set(LineHandle,'Name',InportName)  %设置信号线名称为输入模块名称
    set(LineHandle,'MustResolveToSignalObject',1)   %设置信号线关联Simulink Signal Object
end

OurportCell = find_system(bdroot,'SearchDepth',1,'BlockType','Outport');  %获取顶层Outport模块路径
for i = 1:length(OurportCell)  
    OutportName = get_param(OurportCell{i},'Name');  %输入模块名称
    OutportHandle = get_param(OurportCell{i},'Handle');  %信号线句柄
    LineHandleStruct = get(OutportHandle,'LineHandles');
    LineHandle = LineHandleStruct.Inport;
    set(LineHandle,'Name',OutportName)  %设置信号线名称为输入模块名称
    set(LineHandle,'MustResolveToSignalObject',1)   %设置信号线关联Simulink Signal Object
end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = ' 快捷脚本';
            app.UIFigure.Icon = 'sw.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.Tag = 'zrkkk';
            app.a1 = uibutton(app.UIFigure, 'push');
            app.a1.ButtonPushedFcn = createCallbackFcn(app, @a1ButtonPushed, true);
            app.a1.Tag = '嗒嗒嗒';
            app.a1.Tooltip = {''};
            app.a1.Text = '端口命名';
            app.a1_2 = uibutton(app.UIFigure, 'push');
            app.a1_2.ButtonPushedFcn = createCallbackFcn(app, @a1_2ButtonPushed, true);
            app.a1_2.Tag = '嗒嗒嗒';
            app.a1_2.Tooltip = {''};
            app.a1_2.Text = '模块间连线';
            app.a1_3 = uibutton(app.UIFigure, 'push');
            app.a1_3.ButtonPushedFcn = createCallbackFcn(app, @a1_3ButtonPushed, true);
            app.a1_3.Tag = '嗒嗒嗒';
            app.a1_3.Tooltip = {''};
            app.a1_3.Text = '复制模块的Mask 标定量';
            app.a1_4 = uibutton(app.UIFigure, 'push');
            app.a1_4.ButtonPushedFcn = createCallbackFcn(app, @a1_4ButtonPushed, true);
            app.a1_4.Tag = '嗒嗒嗒';
            app.a1_4.Tooltip = {''};
            app.a1_4.Text = '修改模块形状';
            app.a1_5 = uibutton(app.UIFigure, 'push');
            app.a1_5.ButtonPushedFcn = createCallbackFcn(app, @a1_5ButtonPushed, true);
            app.a1_5.Tag = '嗒嗒嗒';
            app.a1_5.Tooltip = {''};
            app.a1_5.Text = ' Mask格式转换 ';
            app.a1_6 = uibutton(app.UIFigure, 'push');
            app.a1_6.ButtonPushedFcn = createCallbackFcn(app, @a1_6ButtonPushed, true);
            app.a1_6.Tag = '嗒嗒嗒';
            app.a1_6.Tooltip = {''};
            app.a1_6.Text = '根据输出、输入模块制作fromworks模块';
            app.a1_7 = uibutton(app.UIFigure, 'push');
            app.a1_7.ButtonPushedFcn = createCallbackFcn(app, @a1_7ButtonPushed, true);
            app.a1_7.Tag = '嗒嗒嗒';
            app.a1_7.Tooltip = {''};
            app.a1_7.Text = '整理模块布局';
            app.a1_8 = uibutton(app.UIFigure, 'push');
            app.a1_8.ButtonPushedFcn = createCallbackFcn(app, @a1_8ButtonPushed, true);
            app.a1_8.Tag = '嗒嗒嗒';
            app.a1_8.Tooltip = {''};
            app.a1_8.Text = '根据"_Sim"制作fromworks模块';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonButtonPushed, true);
            app.Button.Tag = '嗒嗒嗒';
            app.Button.Tooltip = {''};
            app.Button.Text = '自动连线';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '清除信号线名称';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '设置信号线名称';
            app.zhaoRongkangMATLAB2021bLabel = uilabel(app.UIFigure);
            app.zhaoRongkangMATLAB2021bLabel.Text = {''; '%zhao Rongkang'; '% MATLAB 2021b'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ditton

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end